classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        realImage                      matlab.ui.control.Image
        backBtn                        matlab.ui.control.Button
        nextBtn                        matlab.ui.control.Button
        FechaImagenDatePickerLabel     matlab.ui.control.Label
        FechaImagenDatePicker          matlab.ui.control.DatePicker
        kMeanImage                     matlab.ui.control.Image
        thresholdImg                   matlab.ui.control.Image
        KmeanreaconvegetacinLabel      matlab.ui.control.Label
        ColormaskreaconvegetacinLabel  matlab.ui.control.Label
        BasededatosImagenesDropDownLabel  matlab.ui.control.Label
        dbDropDown                     matlab.ui.control.DropDown
        AreaverdeLabel                 matlab.ui.control.Label
        areaVerdeValue                 matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global dataLoaderL
            global dataLoaderK 
            global dataLoaderT
            global datasets
            global step
            datasets = DatasetLoader().initialize();
            allPaths = datasets.getAll();
            app.dbDropDown.Items = allPaths;
            app.dbDropDown.Value = allPaths(1);
            path = datasets.getElement(1).Data.path;
            real = strcat(path,"\Landsat");
            kmeans = strcat(path,"\kmeans");
            thresh = strcat(path,"\thresholding");
            dataLoaderL = DataLoader().initialize(real, true);
            dataLoaderK = DataLoader().initialize(kmeans, true);
            dataLoaderT = DataLoader().initialize(thresh, true);
            step = 1;
            imageData = dataLoaderL.getElement(step).Data;
            year = str2num(imageData.year);
            month = str2num(imageData.month);
            day = str2num(imageData.day);
            date = datetime(year,month,day);
            app.areaVerdeValue.Text = num2str(imageData.forestArea);
            app.FechaImagenDatePicker.Value = date;
            app.realImage.ImageSource = imageData.imagePath;
            app.kMeanImage.ImageSource = dataLoaderK.getElement(step).Data.imagePath;
            app.thresholdImg.ImageSource = dataLoaderT.getElement(step).Data.imagePath;
            [dates, areas] = dataLoaderL.getPlotElements()
            bar(app.UIAxes, areas)
            app.UIAxes.XTickLabel = dates;
        end

        % Button pushed function: nextBtn
        function nextBtnPushed(app, event)
            global dataLoaderL
            global dataLoaderK
            global dataLoaderT
            global step
            size = dataLoaderL.size;
            if(step+1 <= size)
                step = step+1;
            end
            if(step <= size)
                imageData = dataLoaderL.getElement(step).Data;
                app.realImage.ImageSource = imageData.imagePath;
                app.kMeanImage.ImageSource = dataLoaderK.getElement(step).Data.imagePath;
                app.thresholdImg.ImageSource = dataLoaderT.getElement(step).Data.imagePath;
                year = str2num(imageData.year);
                month = str2num(imageData.month);
                day = str2num(imageData.day);
                date = datetime(year,month,day);
                app.areaVerdeValue.Text = num2str(imageData.forestArea);
                app.FechaImagenDatePicker.Value = date;
            end
            
            
        end

        % Button pushed function: backBtn
        function backBtnButtonPushed(app, event)
            global dataLoaderL
            global dataLoaderK
            global dataLoaderT
            global step
            if(step-1 >= 1)
                step = step-1;
            end
            if(step >= 1)
                imageData = dataLoaderL.getElement(step).Data;
                app.realImage.ImageSource = imageData.imagePath;
                app.kMeanImage.ImageSource = dataLoaderK.getElement(step).Data.imagePath;
                app.thresholdImg.ImageSource = dataLoaderT.getElement(step).Data.imagePath;
                year = str2num(imageData.year);
                month = str2num(imageData.month);
                day = str2num(imageData.day);
                date = datetime(year,month,day);
                app.areaVerdeValue.Text = num2str(imageData.forestArea);
                app.FechaImagenDatePicker.Value = date;
            end
        end

        % Value changed function: dbDropDown
        function dbDropDownValueChanged(app, event)
            global datasets
            global dataLoaderL
            global dataLoaderK 
            global dataLoaderT
            global step
            value = app.dbDropDown.Value;
            if(strcmp(value, 'Crear Nueva'))
                answer = questdlg(['¿Quiere crear una nueva base de datos para el calculo de vegetacion?'], ...
            	'Operacion de calculo de vegetacion', ...
            	'Si','No','Don''t know');
                switch answer
                    case 'Si'
                        idx = num2str(datasets.size+1);
                        newDir = strcat('./Data/SATELLITE_',idx);
                        mkdir(newDir)
                        mkdir(strcat(newDir, '/Landsat'))
                        mkdir(strcat(newDir, '/kmeans'))
                        mkdir(strcat(newDir, '/thresholding'))
                        warndlg('Ingrese las imagenes en la siguiente carpeta.','Calculo de vegetacion');
                        winopen(strcat(newDir, '/Landsat'));
                        pause(3)
                        answer = questdlg(['¿Quiere continuar con el proceso de calculo de vegetacion? Esto tomara mucho tiempo'], ...
                    	'Operacion de calculo de vegetacion', ...
                    	'Si','No','Don''t know');
                        switch answer
                            case 'Si'
                                idx = str2num(idx);
                                
                                datasets = DatasetLoader().initialize();
                                allPaths = datasets.getAll();
                                app.dbDropDown.Items = allPaths;
                                app.dbDropDown.Value = allPaths(idx);
                                path = datasets.getElement(idx).Data.path;
                                real = strcat(path,"\Landsat");
                                kmeans = strcat(path,"\kmeans");
                                thresh = strcat(path,"\thresholding");
                                dataLoaderL = DataLoader().initialize(real, false);
                                step = 1;
                                ImgProcessing.processData(dataLoaderL);
                                dataLoaderL = DataLoader().initialize(real, true);
                                imageData = dataLoaderL.getElement(step).Data;
                                year = str2num(imageData.year);
                                month = str2num(imageData.month);
                                day = str2num(imageData.day);
                                date = datetime(year,month,day);
                                app.FechaImagenDatePicker.Value = date;
                                app.areaVerdeValue.Text = num2str(imageData.forestArea);
                                app.realImage.ImageSource = imageData.imagePath;
                                dataLoaderK = DataLoader().initialize(kmeans, true);
                                dataLoaderT = DataLoader().initialize(thresh, true);
                                app.kMeanImage.ImageSource = dataLoaderK.getElement(step).Data.imagePath;
                                app.thresholdImg.ImageSource = dataLoaderT.getElement(step).Data.imagePath;
                                [dates, areas] = dataLoaderL.getPlotElements()
                                bar(app.UIAxes, areas)
                                app.UIAxes.XTickLabel = dates;
                            case 'No'
                                warndlg('Operacion cancelada','Calculo de vegetacion');
                                app.dbDropDown.Value = event.PreviousValue
                               
                        end
                    case 'No'
                        warndlg('Operacion cancelada','Calculo de vegetacion');
                        app.dbDropDown.Value = event.PreviousValue
                end               
            else
                valueSplit = split(value, '_')
                idx = str2num(cell2mat(valueSplit(2)));
                allPaths = datasets.getAll();
                app.dbDropDown.Items = allPaths;
                app.dbDropDown.Value = allPaths(idx);
                path = datasets.getElement(idx).Data.path;
                real = strcat(path,"\Landsat");
                kmeans = strcat(path,"\kmeans");
                thresh = strcat(path,"\thresholding");
                dataLoaderL = DataLoader().initialize(real, true);
                dataLoaderK = DataLoader().initialize(kmeans, true);
                dataLoaderT = DataLoader().initialize(thresh, true);
                step = 1;
                imageData = dataLoaderL.getElement(step).Data;
                year = str2num(imageData.year);
                month = str2num(imageData.month);
                day = str2num(imageData.day);
                date = datetime(year,month,day);
                app.FechaImagenDatePicker.Value = date;
                app.areaVerdeValue.Text = num2str(imageData.forestArea);
                app.realImage.ImageSource = imageData.imagePath;
                app.kMeanImage.ImageSource = dataLoaderK.getElement(step).Data.imagePath;
                app.thresholdImg.ImageSource = dataLoaderT.getElement(step).Data.imagePath;
                [dates, areas] = dataLoaderL.getPlotElements()
                bar(app.UIAxes, areas)
                app.UIAxes.XTickLabel = dates;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.realImage = uiimage(app.UIFigure);
            app.realImage.Tag = 'real';
            app.realImage.ImageSource = 'Image_1.jpg';
            app.backBtn = uibutton(app.UIFigure, 'push');
            app.backBtn.ButtonPushedFcn = createCallbackFcn(app, @backBtnButtonPushed, true);
            app.backBtn.Icon = 'arrow_l.png';
            app.backBtn.Text = '';
            app.nextBtn = uibutton(app.UIFigure, 'push');
            app.nextBtn.ButtonPushedFcn = createCallbackFcn(app, @nextBtnPushed, true);
            app.nextBtn.Tag = 'next_btn';
            app.nextBtn.Icon = 'arrow_r.png';
            app.nextBtn.Text = '';
            app.FechaImagenDatePickerLabel = uilabel(app.UIFigure);
            app.FechaImagenDatePickerLabel.Enable = 'off';
            app.FechaImagenDatePickerLabel.Text = 'Fecha Imagen';
            app.FechaImagenDatePicker = uidatepicker(app.UIFigure);
            app.FechaImagenDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.FechaImagenDatePicker.Editable = 'off';
            app.FechaImagenDatePicker.Enable = 'off';
            app.kMeanImage = uiimage(app.UIFigure);
            app.kMeanImage.ImageSource = 'Image_2.jpg';
            app.thresholdImg = uiimage(app.UIFigure);
            app.thresholdImg.ImageSource = 'Image_2.jpg';
            app.KmeanreaconvegetacinLabel = uilabel(app.UIFigure);
            app.KmeanreaconvegetacinLabel.Text = 'K-mean área con vegetación: ';
            app.ColormaskreaconvegetacinLabel = uilabel(app.UIFigure);
            app.ColormaskreaconvegetacinLabel.Text = 'Color mask área con vegetación: ';
            app.BasededatosImagenesDropDownLabel = uilabel(app.UIFigure);
            app.BasededatosImagenesDropDownLabel.Text = {'Base de datos Imagenes'; ''};
            app.dbDropDown = uidropdown(app.UIFigure);
            app.dbDropDown.Items = {'Crear Nueva'};
            app.dbDropDown.ValueChangedFcn = createCallbackFcn(app, @dbDropDownValueChanged, true);
            app.dbDropDown.Value = 'Crear Nueva';
            app.AreaverdeLabel = uilabel(app.UIFigure);
            app.AreaverdeLabel.Text = 'Area verde:';
            app.areaVerdeValue = uilabel(app.UIFigure);
            app.areaVerdeValue.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cantidad de vegetacion a lo largo del tiempo')
            xlabel(app.UIAxes, 'Fecha')
            ylabel(app.UIAxes, 'Pixeles / Metros^2')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end